classdef BLM_GUI_Dark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ForceQuitButton                 matlab.ui.control.Button
        SaveTextArea                    matlab.ui.control.TextArea
        CalibrateButton                 matlab.ui.control.Button
        MeasurementLabel                matlab.ui.control.Label
        UpstreamSensibilityButtonGroup  matlab.ui.container.ButtonGroup
        AutosetButton_2                 matlab.ui.control.ToggleButton
        mVdivButton_14                  matlab.ui.control.ToggleButton
        mVdivButton_13                  matlab.ui.control.ToggleButton
        mVdivButton_12                  matlab.ui.control.ToggleButton
        mVdivButton_11                  matlab.ui.control.ToggleButton
        mVdivButton_10                  matlab.ui.control.ToggleButton
        mVdivButton_9                   matlab.ui.control.ToggleButton
        mVdivButton_8                   matlab.ui.control.ToggleButton
        ResetScopeButton                matlab.ui.control.Button
        LightModeButton                 matlab.ui.control.Button
        SetOscilloscopeSensibilityLabel  matlab.ui.control.Label
        DownstreamSensibilityButtonGroup  matlab.ui.container.ButtonGroup
        AutosetButton                   matlab.ui.control.ToggleButton
        mVdivButton_7                   matlab.ui.control.ToggleButton
        mVdivButton_6                   matlab.ui.control.ToggleButton
        mVdivButton_5                   matlab.ui.control.ToggleButton
        mVdivButton_4                   matlab.ui.control.ToggleButton
        mVdivButton_3                   matlab.ui.control.ToggleButton
        mVdivButton_2                   matlab.ui.control.ToggleButton
        mVdivButton                     matlab.ui.control.ToggleButton
        DistanceTextArea_2              matlab.ui.control.TextArea
        DistanceTextArea                matlab.ui.control.TextArea
        OnOffRockerSwitch               matlab.ui.control.RockerSwitch
        SavePlotsButton                 matlab.ui.control.Button
        LoadPlotsButton                 matlab.ui.control.Button
        PositionPlotAxesPart1           matlab.ui.control.UIAxes
        PositionPlotAxesPart2           matlab.ui.control.UIAxes
        CLEARBeamlineAxesPart1          matlab.ui.control.UIAxes
        CLEARBeamlineAxesPart2          matlab.ui.control.UIAxes
        LegendAxes                      matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to app function
    properties (Access = public)
        % Signal data storage
        Fiber_up % upstream signals [nb_meas x time_pts]
        Fiber_down % downstream signals [nb_meas x time_pts]
        
        % Physical and calibration parameters
        diff_excess_fiber = 1.2096 % the difference between the excess fiber between the upstream and downstream fibers in meters. 
        light_speed = 3*10^8 % speed of light
        refr_idx = 1.46 % silicon refractive index
        total_beam_length = 36.2814; % total length (42m?) of beamline (from Alex's result above) in meters

        % Operation parameters
        idx_meas = 1 % current measurement index
        nb_meas = 100000 % total number of measurements to take 
        time_pts = 1000 % number of time points per measurement (each point every 1ns)
        decimation_factor = 10 % decimate the data for faster running by this factor

        % Hardware control
        monitorMTV % Oscilloscope monitor

        % App component dimensions
        CLEARBeamlineAxesHorizontalPixels1 = 556;
        CLEARBeamlineAxesHorizontalPixels2 = 555;

        image1
        image2

    end
    
    % Beam loss monitoring functions
    methods (Access = public) % public can be accessed outside of the app
        
        
        function dist_pixels = Distance_to_pixels(app, dist_meters)
            % Function converts from position in meters to pixels on the
            % CLIC map, by using linear interpolation between known
            % reference points (stored in
            % CLEAR_MAPS_PIXEL_MEASUREMENTS.csv)
            % Inputs:
                % dist_meters: distance along beamline in meters
            % Output:
                % dist_pixels: distance along beamline in pixels
            
            persistent ref_dist_part1 ref_pix_part1 ref_dist_part2 ref_pix_part2 % load reference data once and persist it
            
            if isempty(ref_dist_part1)
                ref_dist_part1 = csvread('CLEAR_MAPS_PIXEL_MEASUREMENTS.csv',4,1,[4,1,12,1]);
                ref_frac_pix_part1 = csvread('CLEAR_MAPS_PIXEL_MEASUREMENTS.csv',4,3,[4,3,12,3]);
                ref_pix_part1 = ref_frac_pix_part1 * app.CLEARBeamlineAxesHorizontalPixels1;

                ref_dist_part2 = csvread('CLEAR_MAPS_PIXEL_MEASUREMENTS.csv',19,1,[19,1,32,1]);
                ref_frac_pix_part2 = csvread('CLEAR_MAPS_PIXEL_MEASUREMENTS.csv',19,3,[19,3,32,3]);
                ref_pix_part2 = ref_frac_pix_part2 * app.CLEARBeamlineAxesHorizontalPixels2;

            end

            if dist_meters <= 20.05 % signal loss lies on CLEAR beamline part 1
                % vectorised interpolation between reference points to find
                % beamloss signal in pixels. "extrapolation" in case out of
                % beamline.
                dist_pixels = interp1(ref_dist_part1, ref_pix_part1, dist_meters, "linear", "extrap");
            else % signal loss lies on CLEAR beamline part 2
                dist_pixels = interp1(ref_dist_part2, ref_pix_part2, dist_meters, "linear", "extrap");
            end
        
        
        end


        function [rise_idx] = Find_rise_time_idx(~, in_data)
            % Function finds the indices (ie time samples) in the signal array 
            % where any beam loss has occurred. It finds where the UPSTREAM
            % input data has an average gradient (over 10 samples) higher
            % than a certain threshold and returns the index of that point.
            % Inputs:
                % in_data: input signal (1x1000 array)
            % Outputs:
                % rise_idx: array of indices where beam loss occurred

            gradients_up = gradient(in_data(1,:)); % instantaneous gradients of upstream data 
            window_size = 10;
            mean_gradients_up = movmean(gradients_up, window_size); % mean gradients over every 10 samples
            
            
            % use first 200 samples to represent the steady-state
            % background signal (with no beam loss). The average of this
            % gives the baseline offset.
            % max(in_data - background) gives the peak signal
            % deviation from the baseline. define threshold gradient as
            % 1/50th of the peak signal amplitude per ns:

            % Calculate the threshold gradient
            background = mean(in_data(1:200));
            t_width = 1; % time resolution of the signal (in ns)
            threshold_rise = 40; % empirically chosen for the threshold_grad: a valid loss should have slope 1/50th of the peak signal amplitude per ns
            % smaller threshold_rise gives higher threshold            
            threshold_grad = max(in_data - background) / (threshold_rise/t_width);

            % find the places where the gradient exceeds the threshold:
            candidate = find(mean_gradients_up == max(mean_gradients_up)); % find index of maximum gradient
            if isempty(candidate)
                rise_idx = 0;
                return
            elseif mean_gradients_up(candidate) > threshold_grad % check whether maximum gradient exceeds the threshold
                rise_idx = candidate;
            end
        end


        function time_diff = Find_time_diff(~, loss_idx_down, loss_idx_up)
            % Function calls Acquire_signal to get the averaged upstream and
            % downstream signals, then uses Find_rise_time_idx to find the
            % time of beam loss. Calculates the time difference between the
            % upstream and downstream beam loss.

            % [~, ~, avg_up_data, avg_down_data] = Acquire_signal(app); % acquire upstream and downstream signal and averaged signal
            % 
            % loss_indices_up = Find_rise_time_idx(app,avg_up_data);  
            % loss_indices_down = Find_rise_time_idx(app,avg_down_data);
            % 
            % loss_idx_up = loss_indices_up(1); % only take the first rising edge
            % loss_idx_down = loss_indices_down(1);
            time_diff = loss_idx_down - loss_idx_up; 

        end


        function [beam_loss_dist] = Find_beam_loss_dist(app, time_diff)
            % This function finds the beam loss position (in meters) from
            % the start of the beamline, using the time difference between
            % the time index of the upstream beam loss and the time index
            % of the downstream beam loss. 

            % loss_idx_up: time index of beam loss using Find_rise_time_idx
            % on the upstream (averaged) signal 
            % loss_idx_down: time index of beam loss using Find_rise_time_idx
            % on the downstream (averaged) signal 

            % time_diff = Find_time_diff(app); % time difference between upstream beam loss rising edge and downstream beam loss rising edge in ns
            beam_loss_dist = (- app.light_speed/app.refr_idx * time_diff * 10^-9 + app.diff_excess_fiber + app.total_beam_length)/2 ; % calculation of beam loss position 

        end


        function [up_data, down_data, smooth_data_up, smooth_data_down] = Acquire_signal(app)
            % This function acquires the raw upstream and downstream signals
            % both for singular measurements of 1000 time samples, and also
            % the averaged and smoothed (using Sgolayfilt) data for
            % upstream and downstream.
            Fiber_down_all = matlabJapc.staticGetSignal('SCT.USER.SETUP','CA.SCOPE10.CH02/Acquisition') ;
            Fiber_up_all = matlabJapc.staticGetSignal('SCT.USER.SETUP','CA.SCOPE10.CH01/Acquisition') ;
            
            % app.Fiber_down(app.idx_meas, :) = decimate(double(Fiber_down_all.value) .* Fiber_down_all.sensitivity + Fiber_down_all.offset, app.decimation_factor);
            % app.Fiber_up(app.idx_meas, :) = decimate(double(Fiber_up_all.value) .* Fiber_up_all.sensitivity + Fiber_up_all.offset, app.decimation_factor);

            app.Fiber_down(app.idx_meas, :) = double(Fiber_down_all.value) .* Fiber_down_all.sensitivity + Fiber_down_all.offset;
            app.Fiber_up(app.idx_meas, :) = double(Fiber_up_all.value) .* Fiber_up_all.sensitivity + Fiber_up_all.offset;

            down_data = squeeze(app.Fiber_down(app.idx_meas,:));                        
            up_data = squeeze(app.Fiber_up(app.idx_meas,:));

            [avg_up_data, avg_down_data] = Data_Averager(app);

            [smooth_data_up, smooth_data_down] = Smooth_data(avg_up_data, avg_down_data);

            function [avg_up_data, avg_down_data] = Data_Averager(app)
                % Function cleans up the BLM signal by averaging over the last
                % 5 measurements (1 measurement is acquisition for 1000ns) for
                % all measurements after the 10th measurement index. indx_meas:
                % measurement index, each measurement is 1000 1ns time samples.
    
                if app.idx_meas > 10
                    avg_up_data = mean(app.Fiber_up((app.idx_meas - 4):app.idx_meas, :), 1);
                    avg_down_data = mean(app.Fiber_down((app.idx_meas - 4):app.idx_meas, :), 1);
                else
                    avg_up_data(1,:) = squeeze(app.Fiber_up(app.idx_meas,:));
                    avg_down_data(1,:) = squeeze(app.Fiber_down(app.idx_meas,:));
                end
            end
                    

            function [smooth_data_up, smooth_data_down] = Smooth_data(avg_up_data, avg_down_data)
                % Function smooths the averaged signal using a
                % Savitsky-Golay filter.
                order = 2; % polynomial fitting order of the smoothing filter.
                framelen = 51; % number of data points in each window at any one time. 
                smooth_data_up = sgolayfilt(avg_up_data, order, framelen);
                smooth_data_down = sgolayfilt(avg_down_data, order, framelen);

            end
        end


        function Plot_signal_beam_loss(app)
            
            [Gun_charge, ~, THz_charge] = Read_BCM ; 

            try

                if app.OnOffRockerSwitch.ValueIndex == 2 && app.idx_meas <= app.nb_meas

                    display(['Meas: ', num2str(app.idx_meas), ' Charge Gun: ', num2str(2*round(Gun_charge,3)),' Charge THz: ', num2str(2*round(THz_charge,3))])
                    % disp(['Meas: ' num2str(app.idx_meas)])

                    app.MeasurementTextArea.Value = {'Measurement number: ', num2str(app.idx_meas) 'Charge at Gun (A): ', num2str(2*round(Gun_charge,3)), 'Charge at THz (A): ', num2str(2*round(THz_charge,3)), 'Percentage beam loss from start of beam (%): ', num2str(100 - round(THz_charge,3)/round(Gun_charge,3) * 100)};                        

                    [up_data, down_data, avg_up_data, avg_down_data] = Acquire_signal(app); % acquire upstream and downstream signal and averaged signal

                    loss_idx_up = Find_rise_time_idx(app,avg_up_data);  
                    loss_idx_down = Find_rise_time_idx(app,avg_down_data);

                    time_diff = Find_time_diff(app, loss_idx_up, loss_idx_down);

                    % Find the distance along the beamline where the
                    % signal is lost (first)
                    beam_loss_dist = Find_beam_loss_dist(app, time_diff);

                    % Convert this distance into pixels
                    beam_loss_pix = Distance_to_pixels(app, beam_loss_dist);

                    app.DistanceTextArea.Value = {'Distance down beamline of maximum beam loss (m): ', num2str(beam_loss_dist)};

                    % plot downstream signal and beam loss points
                    plot(app.UIAxes1,down_data,'magenta');
                    hold(app.UIAxes1, 'on')
                    scatter(app.UIAxes1,loss_idx_down,zeros(size(loss_idx_down)) + mean(down_data),'b','filled')
                    hold(app.UIAxes1, 'off') 

                    % plot upstream signal and beam loss points
                    plot(app.UIAxes2,up_data,'g');
                    hold(app.UIAxes2, 'on')
                    scatter(app.UIAxes2,loss_idx_up,zeros(size(loss_idx_up)) + mean(up_data),'b','filled')
                    hold(app.UIAxes2, 'off') 

                    % plot downstream signal and beam loss points
                    plot(app.UIAxes3,avg_down_data,'magenta');
                    hold(app.UIAxes3, 'on')
                    scatter(app.UIAxes3,loss_idx_down,zeros(size(loss_idx_down)) + mean(avg_down_data),'b','filled')
                    hold(app.UIAxes3, 'off') 

                    % plot upstream signal and beam loss points
                    plot(app.UIAxes4,avg_up_data,'g');
                    hold(app.UIAxes4, 'on')
                    scatter(app.UIAxes4,loss_idx_up,zeros(size(loss_idx_up)) + mean(avg_up_data),'b','filled')
                    hold(app.UIAxes4, 'off') 

                    % plot(app.UIAxes,[beam_loss_pix,beam_loss_pix],[-0.25,1],'LineWidth',3,"Color",[1 0 1])
                    hold(app.PositionPlotAxesPart1, 'off')
                    hold(app.PositionPlotAxesPart2, 'off')

                    if beam_loss_dist <= 20.05
                        % plot the line of beam loss point for the first part of the beamline.
                        cla(app.PositionPlotAxesPart2)
                        % plot(app.UIAxes,[beam_loss_pix,beam_loss_pix],[-0.95,1],'LineWidth',3,"Color",[1 0 1 0.5])
                        plot(app.PositionPlotAxesPart1,[beam_loss_pix,beam_loss_pix],[-1,1],'LineWidth',3,"Color",[1 0 1 0.5])                          
                    else
                        % plot the line of beam loss point for the second part of the beamline.
                        cla(app.PositionPlotAxesPart1)
                        plot(app.PositionPlotAxesPart2,[beam_loss_pix,beam_loss_pix],[-1,1],'LineWidth',3,"Color",[1 0 1 0.5])                                                  
                    end

                    app.idx_meas = app.idx_meas + 1;

                end


                if app.idx_meas > app.nb_meas
                    disp('Finished')
                    app.idx_meas = app.nb_meas + 10^20;
                    app.OnOffRockerSwitch.ValueIndex = 1;
                    app.monitorMTV.stop()
                end

            catch
                error('Breaking out of plotting function.')
            end

        end


        function initialise(app)
            % This function initialises the app/ For as long as the
            % measurement index is less than the total number of
            % measurement indices, this function will call the
            % Plot_Signal_Beam_Loss function to replot the signal data and
            % the beam loss position. 

            addpath /nfs/cs-ccr-nfs6/vol29/Linux/data/clear/MatLab/Operation/Gun_Energy
            try
                matlabJapc.staticINCAify('CTF')
            catch
                disp('OK')
            end
            
            try 
                if app.idx_meas <= app.nb_meas    
                    % app.OnOffRockerSwitch.ValueIndex
                    % Plot_signal_beam_loss(app)
                    % Plot_signal_beam_loss(app)
                    app.monitorMTV = matlabJapcMonitor('SCT.USER.SETUP',{['CA.SCOPE04.CH02','/Acquisition']}, @(data)Plot_signal_beam_loss(app)) ;

                    app.monitorMTV.useRawDataStruct(true) ;
                    app.monitorMTV.stop() ;

                    app.idx_meas = app.idx_meas + 1 ;
                    
                end
    
                if app.idx_meas > app.nb_meas
                    app.idx_meas = app.nb_meas + 10^20 ; % guaranteed stop once measurement index exceeds maximum measurement number. 
                                       
                    if isvalid(app.OnOffRockerSwitch)
                        app.OnOffRockerSwitch = 1;
                    end
                    if isvalid(app.monitorMTV)
                        app.monitorMTV.stop();
                    end
                    
                    
                    disp('Finished')
                    
                end
                

            catch ME
                disp('Error in initialisation: ')
                disp(ME.message)
                disp('Returning to before app initialisation.')
                
                return
                
            end

        end
        
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load in images
            app.image1 = imread('Images/CLEAR_Beamline_new_distances_1.png');
            app.image2 = imread('Images/CLEAR_Beamline_new_distances_2.png');
            imshow(app.image1,'Parent',app.CLEARBeamlineAxesPart1); 
            imshow(app.image2,'Parent',app.CLEARBeamlineAxesPart2); 
            % Initialise and preallocate    
            app.OnOffRockerSwitch.ValueIndex = 1;       
            app.idx_meas = 1;
            app.Fiber_down = zeros(app.nb_meas, app.time_pts);
            app.Fiber_up = zeros(app.nb_meas, app.time_pts);  
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            disp(' ')
            disp('Stopping')
            
            try 
                if isvalid(app) && isprop(app, 'monitorMTV') && isvalid(app.monitorMTV)
                    app.monitorMTV.stop()
                    delete(app.UIFigure)
                end

            catch ME
                disp('Error during cleanup: ')
                disp(ME.message)
                delete(app.UIFigure)
                error('Breaking out of UIFigureCloseRequest function')
            end
            
            % 
            % delete(app.UIFigure)
            % app.idx_meas = app.nb_meas+10^20;
            % app.OnOffRockerSwitch.ValueIndex = 1;
            % app.monitorMTV.stop()
            % 
            %             disp(' ')
            % disp('Closing App in:')
            % 
            % disp('5')
            % pause(0.5)
            % disp('4')
            % pause(0.5)
            % disp('3')
            % pause(0.5)
            % disp('2')
            % pause(0.5)
            % disp('1')
            % pause(0.5)
            % delete(app)
            % 
            
            
        end

        % Value changed function: OnOffRockerSwitch
        function OnOffRockerSwitchValueChanged(app, event)
            
            try 
                value = app.OnOffRockerSwitch.ValueIndex;
    
                if value == 2
                    
                    disp(' ')
                    disp('Starting')
    
                    initialise(app);
                    
                elseif value == 1
                    
                    disp(' ')
                    disp('Stopping')
                    matlabJapcMonitor('SCT.USER.SETUP',{['CA.SCOPE04.CH02','/Acquisition']}, @(data)Plot_signal_beam_loss(app)).stop()
                    
                end

            catch ME
                disp('Error in rocker switch callback: ');
                disp(ME.message)
            end

            
        end

        % Button pushed function: ForceQuitButton
        function ForceQuitButtonPushed(app, event)
            disp(' ')
            disp('Force Closing')
            
            try
                app.idx_meas = app.nb_meas+10^20;
                matlabJapcMonitor('SCT.USER.SETUP',{['CA.SCOPE04.CH02','/Acquisition']}).stop()
                app.OnOffRockerSwitch.ValueIndex = 1;
            catch
            end
            
            delete(app)
        end

        % Button pushed function: SavePlotsButton
        function SavePlotsButtonPushed(app, event)
            % Function saves the upstream and downstream raw and smoothed
            % data to a txt file. Also saves a screenshot of the position
            % along the beamline where loss occured. 
            % [up_data, down_data, avg_up_data, avg_down_data] = Acquire_signal(app);
            % [up_data, down_data, avg_up_data, avg_down_data] = [1, 1, 1, 1];
            % T = table(up_data, down_data, avg_up_data, avg_down_data);
            % writetable(T, ['Saved BLM data/BLM_GUI_data_', char(datetime('now', 'Format', 'ddMMyyyy-HH:mm:ss')), '.txt']);
            exportapp(app.UIFigure, ['Saved BLM data/BLM_GUI_data_', char(datetime('now', 'Format', 'ddMMyyyy-HH:mm:ss')),'.jpg']);
            disp(' ')
            disp('BLM data saved.');
            app.SaveTextArea.Visible = 'on';            
            app.SaveTextArea.Value = {'' ; '' ; 'Plots Saved.' ; ''};
            pause(1)
            app.SaveTextArea.Visible = 'off';
        end

        % Selection changed function: DownstreamSensibilityButtonGroup
        function DownstreamSensibilityChanged(app, event)
            selectedButton = app.DownstreamSensibilityButtonGroup.SelectedObject;
            selectedButton
        end

        % Button pushed function: LightModeButton
        function DarkLightModePressed(app, event)
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            disp('hello')
        end

        % Button pushed function: ResetScopeButton
        function ResetScopeButtonPushed(app, event)
            
            matlabJapc.staticSetSignal('','CX.SMEAS-BPMP/OutEnable#outEnabled', 0)
            matlabJapc.staticSetSignal('','CX.SMEAS-BPMP/OutEnable#outEnabled', 1)
            
        end

        % Button pushed function: LoadPlotsButton
        function LoadPlotsButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Smoothed Upstream Signal')
            xlabel(app.UIAxes4, 'Index')
            ylabel(app.UIAxes4, 'Signal')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Smoothed Downstream Signal')
            xlabel(app.UIAxes3, 'Index')
            ylabel(app.UIAxes3, 'Signal')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Raw Upstream Signal')
            xlabel(app.UIAxes2, 'Index')
            ylabel(app.UIAxes2, 'Signal')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Raw Downstream Signal')
            xlabel(app.UIAxes1, 'Index')
            ylabel(app.UIAxes1, 'Signal')
            app.LegendAxes = uiaxes(app.UIFigure);
            title(app.LegendAxes, 'Legend')
            app.LegendAxes.XTick = [];
            app.LegendAxes.YTick = [];
            app.CLEARBeamlineAxesPart2 = uiaxes(app.UIFigure);
            title(app.CLEARBeamlineAxesPart2, 'CLEAR Beamline Part 2')
            app.CLEARBeamlineAxesPart2.XTick = [];
            app.CLEARBeamlineAxesPart2.YTick = [];
            app.CLEARBeamlineAxesPart1 = uiaxes(app.UIFigure);
            title(app.CLEARBeamlineAxesPart1, 'CLEAR Beamline Part 1')
            app.CLEARBeamlineAxesPart1.XTick = [];
            app.CLEARBeamlineAxesPart1.YTick = [];
            app.PositionPlotAxesPart2 = uiaxes(app.UIFigure);
            app.PositionPlotAxesPart2.XLim = [0 556];
            app.PositionPlotAxesPart2.YLim = [-1 1];
            app.PositionPlotAxesPart2.XTick = [];
            app.PositionPlotAxesPart2.YTick = [];
            app.PositionPlotAxesPart1 = uiaxes(app.UIFigure);
            app.PositionPlotAxesPart1.XTick = [];
            app.PositionPlotAxesPart1.YTick = [];
            app.LoadPlotsButton = uibutton(app.UIFigure, 'push');
            app.LoadPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotsButtonPushed, true);
            app.LoadPlotsButton.Text = 'Load Plots';
            app.SavePlotsButton = uibutton(app.UIFigure, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotsButtonPushed, true);
            app.SavePlotsButton.Text = 'Save Plots';
            app.OnOffRockerSwitch = uiswitch(app.UIFigure, 'rocker');
            app.OnOffRockerSwitch.Items = {'Stop', 'Start'};
            app.OnOffRockerSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffRockerSwitchValueChanged, true);
            app.OnOffRockerSwitch.Value = 'Stop';
            app.DistanceTextArea = uitextarea(app.UIFigure);
            app.DistanceTextArea.Editable = 'off';
            app.DistanceTextArea.Value = {'Distance to beam loss:'};
            app.DistanceTextArea_2 = uitextarea(app.UIFigure);
            app.DistanceTextArea_2.Editable = 'off';
            app.DistanceTextArea_2.Value = {'24.8 m'};
            app.DownstreamSensibilityButtonGroup = uibuttongroup(app.UIFigure);
            app.DownstreamSensibilityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DownstreamSensibilityChanged, true);
            app.DownstreamSensibilityButtonGroup.Title = 'Downstream';
            app.mVdivButton = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.mVdivButton.Text = '5mV/div';
            app.mVdivButton.Value = true;
            app.mVdivButton_2 = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.mVdivButton_2.Text = '10mV/div';
            app.mVdivButton_3 = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.mVdivButton_3.Text = '20mV/div';
            app.mVdivButton_4 = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.mVdivButton_4.Text = '50mV/div';
            app.mVdivButton_5 = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.mVdivButton_5.Text = '100mV/div';
            app.mVdivButton_6 = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.mVdivButton_6.Text = '200mV/div';
            app.mVdivButton_7 = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.mVdivButton_7.Text = '500mV/div';
            app.AutosetButton = uitogglebutton(app.DownstreamSensibilityButtonGroup);
            app.AutosetButton.Text = 'Auto set';
            app.SetOscilloscopeSensibilityLabel = uilabel(app.UIFigure);
            app.SetOscilloscopeSensibilityLabel.Text = 'Set Oscilloscope Sensibility';
            app.LightModeButton = uibutton(app.UIFigure, 'push');
            app.LightModeButton.ButtonPushedFcn = createCallbackFcn(app, @DarkLightModePressed, true);
            app.LightModeButton.Text = 'Light Mode';
            app.ResetScopeButton = uibutton(app.UIFigure, 'push');
            app.ResetScopeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScopeButtonPushed, true);
            app.ResetScopeButton.Text = 'Reset Scope';
            app.UpstreamSensibilityButtonGroup = uibuttongroup(app.UIFigure);
            app.UpstreamSensibilityButtonGroup.Title = 'Upstream';
            app.mVdivButton_8 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.mVdivButton_8.Text = '5mV/div';
            app.mVdivButton_8.Value = true;
            app.mVdivButton_9 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.mVdivButton_9.Text = '10mV/div';
            app.mVdivButton_10 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.mVdivButton_10.Text = '20mV/div';
            app.mVdivButton_11 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.mVdivButton_11.Text = '50mV/div';
            app.mVdivButton_12 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.mVdivButton_12.Text = '100mV/div';
            app.mVdivButton_13 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.mVdivButton_13.Text = '200mV/div';
            app.mVdivButton_14 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.mVdivButton_14.Text = '500mV/div';
            app.AutosetButton_2 = uitogglebutton(app.UpstreamSensibilityButtonGroup);
            app.AutosetButton_2.Text = 'Auto set';
            app.MeasurementLabel = uilabel(app.UIFigure);
            app.MeasurementLabel.Text = {'Measurement number: '; 'Charge Gun: '; 'Charge (THz): '; 'Chage (THz2):'};
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.SaveTextArea = uitextarea(app.UIFigure);
            app.SaveTextArea.Editable = 'off';
            app.SaveTextArea.Visible = 'off';
            app.ForceQuitButton = uibutton(app.UIFigure, 'push');
            app.ForceQuitButton.ButtonPushedFcn = createCallbackFcn(app, @ForceQuitButtonPushed, true);
            app.ForceQuitButton.Text = {' Force '; 'Quit'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLM_GUI_Dark

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end